classdef entrenamiento_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        lienzoFig                    matlab.ui.Figure
        instructionsMenu             matlab.ui.container.Menu
        dataAdquisitionInstructions  matlab.ui.container.Menu
        myoMenu                      matlab.ui.container.Menu
        myoFigure                    matlab.ui.container.Menu
        myoDiagnostics               matlab.ui.container.Menu
        gForceMenu                   matlab.ui.container.Menu
        gForceFigure                 matlab.ui.container.Menu
        videosGestures               matlab.ui.container.Menu
        syncGesture                  matlab.ui.container.Menu
        relaxGesture                 matlab.ui.container.Menu
        fistGesture                  matlab.ui.container.Menu
        openGesture                  matlab.ui.container.Menu
        waveInGesture                matlab.ui.container.Menu
        waveOutGesture               matlab.ui.container.Menu
        upG                          matlab.ui.container.Menu
        downG                        matlab.ui.container.Menu
        leftG                        matlab.ui.container.Menu
        rightG                       matlab.ui.container.Menu
        forwardG                     matlab.ui.container.Menu
        backG                        matlab.ui.container.Menu
        acercaDe                     matlab.ui.container.Menu
        contactInformation           matlab.ui.container.Menu
        HelpMenu                     matlab.ui.container.Menu
        RecuperateMenu               matlab.ui.container.Menu
        Toolbar                      matlab.ui.container.Toolbar
        PushTool                     matlab.ui.container.toolbar.PushTool
        uipanel9                     matlab.ui.container.Panel
        txt_version                  matlab.ui.control.Label
        text46                       matlab.ui.control.Label
        text45                       matlab.ui.control.Label
        text44                       matlab.ui.control.Label
        escudoAxes                   matlab.ui.control.UIAxes
        buhoAxes                     matlab.ui.control.UIAxes
        uipanel17                    matlab.ui.container.Panel
        RecuperatePanel              matlab.ui.container.Panel
        AcceptButton                 matlab.ui.control.Button
        NamesuserDataLabel           matlab.ui.control.Label
        NameEditField                matlab.ui.control.EditField
        NameEditFieldLabel           matlab.ui.control.Label
        bienvenidoText               matlab.ui.control.Label
        msjText                      matlab.ui.control.Label
        tituloGestoText              matlab.ui.control.Label
        repeticionesText             matlab.ui.control.Label
        uipanel14                    matlab.ui.container.Panel
        restaurarButton              matlab.ui.control.Button
        empezarEntrenamientoButton   matlab.ui.control.Button
        uipanel16                    matlab.ui.container.Panel
        repetirButton                matlab.ui.control.Button
        grabarButton                 matlab.ui.control.Button
        gestoAxes                    matlab.ui.control.UIAxes
        waitbarAxes                  matlab.ui.control.UIAxes
        uipanel6                     matlab.ui.container.Panel
        lesionCheck                  matlab.ui.control.CheckBox
        myoPanel                     matlab.ui.container.ButtonGroup
        listOfMyos                   matlab.ui.control.DropDown
        uipanel5                     matlab.ui.container.Panel
        text36                       matlab.ui.control.Label
        text18                       matlab.ui.control.Label
        timeGestureText              matlab.ui.control.EditField
        text13                       matlab.ui.control.Label
        numRepRelaxText              matlab.ui.control.EditField
        numRepText                   matlab.ui.control.EditField
        brazoPanel                   matlab.ui.container.Panel
        text28                       matlab.ui.control.Label
        text29                       matlab.ui.control.Label
        codMyoText                   matlab.ui.control.EditField
        cubCodoText                  matlab.ui.control.EditField
        text26                       matlab.ui.control.Label
        perimetroText                matlab.ui.control.EditField
        uipanel2                     matlab.ui.container.Panel
        muestrasQuat_txt             matlab.ui.control.Label
        text68                       matlab.ui.control.Label
        text66                       matlab.ui.control.Label
        text65                       matlab.ui.control.Label
        text64                       matlab.ui.control.Label
        text63                       matlab.ui.control.Label
        text62                       matlab.ui.control.Label
        text61                       matlab.ui.control.Label
        text59                       matlab.ui.control.Label
        text58                       matlab.ui.control.Label
        muestrasText                 matlab.ui.control.Label
        text53                       matlab.ui.control.Label
        rotAxes                      matlab.ui.control.UIAxes
        emg5Axes                     matlab.ui.control.UIAxes
        emg4Axes                     matlab.ui.control.UIAxes
        emg3Axes                     matlab.ui.control.UIAxes
        emg2Axes                     matlab.ui.control.UIAxes
        emg6Axes                     matlab.ui.control.UIAxes
        emg7Axes                     matlab.ui.control.UIAxes
        emg8Axes                     matlab.ui.control.UIAxes
        emg1Axes                     matlab.ui.control.UIAxes
        configuracionPanel           matlab.ui.container.Panel
        surveyButton                 matlab.ui.control.Button
        htUserText                   matlab.ui.control.EditField
        text77                       matlab.ui.control.Label
        wtUserText                   matlab.ui.control.EditField
        text76                       matlab.ui.control.Label
        spoUserText                  matlab.ui.control.EditField
        text75                       matlab.ui.control.Label
        bloodPressureUserText        matlab.ui.control.EditField
        text74                       matlab.ui.control.Label
        TempUserText                 matlab.ui.control.EditField
        text73                       matlab.ui.control.Label
        genderGroup                  matlab.ui.container.ButtonGroup
        mujerRadio                   matlab.ui.control.RadioButton
        hombreRadio                  matlab.ui.control.RadioButton
        ocupacionText                matlab.ui.control.DropDown
        text55                       matlab.ui.control.Label
        handednessGroup              matlab.ui.container.ButtonGroup
        zurdoRadio                   matlab.ui.control.RadioButton
        diestroRadio                 matlab.ui.control.RadioButton
        edadText                     matlab.ui.control.EditField
        text24                       matlab.ui.control.Label
        text21                       matlab.ui.control.Label
        nameUserText                 matlab.ui.control.EditField
        uipanel18                    matlab.ui.container.Panel
        bat_txt                      matlab.ui.control.Label
        text70                       matlab.ui.control.Label
        connectGForce_Button         matlab.ui.control.Button
        text23                       matlab.ui.control.Label
        connectMyo_Button            matlab.ui.control.Button
        connectLedAxes               matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        function emailText_CreateFcn(app, hObject, eventdata, handles)
            % --- Executes during object creation, after setting all properties.
            
            % hObject    handle to emailText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function etniaText_CreateFcn(app, hObject, eventdata, handles)
            
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function gesturesListBox_CreateFcn(app, hObject, eventdata, handles)
            
            
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function repetirRepButton_Callback(app, hObject, eventdata, handles)
            decreaseIndicesAndLoadImage(handles);
        end
        
        function repetitionsListBox_CreateFcn(app, hObject, eventdata, handles)
            
            
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function entrenamiento_OpeningFcn(app, varargin)
            % Ensure that the app appears on screen when run
            movegui(app.lienzoFig, 'onscreen');
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
               global gesture_set_confs
                
               app.RecuperatePanel.Position = [10068 341 260 123];
             app.RecuperatePanel.Visible= 'off';
            %----------
            handles.output = hObject;
            % Update handles structure
            guidata(hObject, handles);
            movegui(hObject,'center'); % mostrando en el centro
            initWaitbarPro(handles);
            
            imgBuho = imread('buhoEPN.png');
            axes(handles.buhoAxes);
            imagesc(imgBuho);
            axis off;
            % imgEscudo = imread('fisEPN.jpeg');
            imgEscudo = imread('labLogo.png');
            axes(handles.escudoAxes);
            imshow(imgEscudo);
            handles.escudoAxes.Color = 'none';
            axis off;
            
            app.ocupacionText.Value = 'No Selected';
            % version
            handles.txt_version.String = sprintf('ver: %s', configs("version"));
            
            drawnow;
        end

        % Menu selected function: acercaDe
        function acercaDe_Callback(app, event)
            % --------------------------------------------------------------------
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to acercaDe (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            str = {'Data acquisition of Hand Gesture Emg signals'
                ''
                'Artifical Intelligence and Computer Vision Research Lab "Alan Turing"'
                ''
                'Revisión 1: 09 de julio de 2019'
                'Revisión 2: 20 de julio de 2019'
                'Revisión 3: 20 de agosto de 2019'
                'Revisión 4: 3 de febrero de 2021'
                'Revision 5: April 14 2021 (english version)'
                ''
                'Version 1.5: July 28th, 2021'
                'Version 1.7 by Carlos Ayala and Gabriel Macías: June 21st, 2024'
                'Version 1.8 by Alexander Motoche: September 23rd, 2024'
                ''
                ''
                'Version 1 created by Jonathan Zea and Cristhian Motoche'
                'August 2018'};
            msgbox(str,'About...','help');
        end

        % Menu selected function: backG
        function backG_Callback(app, event)
            % --------------------------------------------------------------------
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to backG (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            playGif_App('backward');
        end

        % Value changed function: codMyoText
        function codMyoText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            codMyo = str2double(handles.codMyoText.String);
            if ~isnumeric(codMyo)
                handles.codMyoText.String = '';
                errordlg('must be number', 'Error', 'modal')
            end
        end

        % Button pushed function: connectGForce_Button
        function connectGForce_Button_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            %% Executes on button press in connectGForce_Button.
            % -------------------------------------------------------------------------
            % Warning!
            uiwait(warndlg({'GForce Pro connection is still in beta.', ...
                '¡Be cautious!', 'It is possible that the system crushes.', ...
                '', 'It is recommended to use the device when it is fully charged.'},...
                'WARNING', 'modal'));
            
            global deviceType
            handles.msjText.String = {'Connecting with GForce.', ...
                'This can take several minutes.', ...
                'Please wait...'};
            bloquearGUI(handles); % pause during connection
            ledConexion(handles, 'yellow');
            drawnow
            isConnectedG = connectGForce();
            drawnow
            bloquearGUI(handles, true); % reenable!
            handles.grabarButton.Enable = 'off';
            handles.repetirButton.Enable = 'off';
            drawnow
            
            if isConnectedG
                deviceType = DeviceName.gForce;
                ledConexion(handles, true);
            
                updateBattery(handles);
            
                %-% parámetros de inicio!
                handles.msjText.String = 'Fill the user info and press START!';
                handles.empezarEntrenamientoButton.Enable = 'on';
                handles.restaurarButton.Enable = 'on';
            
                %-% disabling myo button
                handles.connectGForce_Button.Enable = 'off';
                handles.connectMyo_Button.Enable = 'off';
            
                %-% devices
                handles.listOfMyos.String = devices(deviceType);
            else
                handles.msjText.String = 'Can not connect to gForce!';
                ledConexion(handles, false);
                handles.empezarEntrenamientoButton.Enable = 'off';
                handles.restaurarButton.Enable = 'off';
            
                handles.connectGForce_Button.Enable = 'on';
                handles.connectMyo_Button.Enable = 'on';
            end
            drawnow
        end

        % Button pushed function: connectMyo_Button
        function connectMyo_Button_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            %%
            % -------------------------------------------------------------------------
            global deviceType
            handles.msjText.String = 'CONNECTING, please, wait...';
            % isConnectedMyo = connectFakeMyo();
            isConnectedMyo = connectMyo();
            drawnow
            if isConnectedMyo
                deviceType = DeviceName.myo;
                ledConexion(handles, true);
            
                %-% parámetros de inicio!
                handles.msjText.String = 'Fill the user info and press START!';
                handles.empezarEntrenamientoButton.Enable = 'on';
                handles.restaurarButton.Enable = 'on';
            
                %-% disabling gForce_ button
                handles.connectGForce_Button.Enable = 'off';
                handles.connectMyo_Button.Enable = 'off';
                handles.bat_txt.String = '-';
            
                %-% devices
                handles.listOfMyos.String = devices(deviceType);
            else
                handles.msjText.String = {'Could not connect Myo with Matlab', 'Try again'};
                ledConexion(handles, false);
                handles.empezarEntrenamientoButton.Enable = 'off';
                handles.restaurarButton.Enable = 'off';
            end
        end

        % Menu selected function: contactInformation
        function contactInformation_Callback(app, event)
            % --------------------------------------------------------------------
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to contactInformation (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            str = {'CONTACT INFORMATION'
                ''
                'In case you detect an error in this software'
                'please report it by email to:'
                ''
                'Subject: HGR - Data acquistion Software'
                ''
                'Email address:'
                'LABORATORIO DE INVESTIGACION EN INTELIGENCIA Y VISION ARTIFICIAL'
                'laboratorio.ia@epn.edu.ec'
                ''
                'With the screenshot of the error.'
                'Thank you.'};
            
            msgbox(str, 'CONTACT','help');
        end

        % Value changed function: cubCodoText
        function cubCodoText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, ~, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            cubCodo = str2double(handles.cubCodoText.String);
            if ~isnumeric(cubCodo)
                handles.cubCodoText.String = '';
                errordlg('must be number', 'Error', 'modal')
            end
        end

        % Menu selected function: dataAdquisitionInstructions
        function dataAdquisitionInstructions_Callback(app, event)
            % --------------------------------------------------------------------
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to dataAdquisitionInstructions (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            str = {'DATA ACQUISITION INSTRUCTIONS '
                ''
                '1. Check the battery level of the device.'
                '2. Clean foream with alcohol.'
                '3. Wear the device in the right foream.'
                '4. Sync the device.'
                '5. Do “Web diagnostics” (in case of using Myo armband).'
                '6. Connect with Maltab.'
                '7. Fill user info.'
                '8. Take photography of the foream when wearing the device.'
                '9. Do the whole data acquisition.'
                '10. Turn off the device.'
                '11. Clean electrodes with alcohol.'
                ''
                'In the case of an error, restart matlab, restart device.'};
            msgbox(str, 'INSTRUCTIONS','help');
        end

        % Menu selected function: downG
        function downG_Callback(app, event)
            % --------------------------------------------------------------------
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to downG (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            playGif_App('down');
        end

        % Value changed function: edadText
        function edadText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            %% Executes on button press in connectGForce_Button.
            % -------------------------------------------------------------------------
            edad = str2double(handles.edadText.String);
            if ~isnumeric(edad)
                handles.edadText.String = '';
                errordlg('Wrong age', 'Error', 'modal')
            end
        end

        % Button pushed function: empezarEntrenamientoButton
        function empezarEntrenamientoButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            %%
            global userData nombreRecolectorDeDatos respuestas; %
            % Validación de datos
            [isDataValid, msjPrint] = validacionDatos(handles);

            if isDataValid
                % desahabilitar
                bloquearGUI(handles);
            
                drawnow
            
                %-% Get data from GUI
                userData = getUserDataFromHandles(handles);

                app.RecuperateMenu.Enable = 'off';
                %-% Start counters
                userData.counterGesture = 0; % debido al sync gesture
                userData.counterRepetition = 1;
            
                %-% Recolector de datos
                userData.personWhoRecordedData = nombreRecolectorDeDatos;
            
                %-% save!
                createUserData(userData); % creando archivos
                % saveUserData();
            
                waitingFoto(userData.userInfo.username); % loop that waits until foto
                drawnow
            
                %-% GUI
                % not needed anymore!
                handles.bienvenidoText.String = ['Welcome ' userData.userInfo.username];
            
                countGesture = 1; % muestro el primer gesto
                firstGesture = userData.gestures.classes{1};
                repXClass = userData.gestures.repXClass;
                handles.repeticionesText.String = [num2str(...
                    userData.counterRepetition) '/' num2str(repXClass(countGesture))];
            
                handles.tituloGestoText.String = firstGesture;
            
                %dibujarImagen(handles, firstGesture);
                showGestureGif(handles, firstGesture);
                drawnow
            
                % infame sync
                global gesture_set_confs
                if gesture_set_confs.include_sync
                    %-% Recolectar datos de sincronización
                    handles.msjText.String = 'Waiting sync recordings!';
                    %waitfor(playGif('sync')) % sync tutorial!
            
            
                    try
   app = sincronizacion_App();
    uiwait(app.figure1); 
                   % Debe terminar correctamente, si no, se captura el error
                    catch ME
                    % Manejo del error: muestra un mensaje y reanuda la adquisición de datos
                        str = 'Error en sincronización. Se reanudará la adquisición de datos.';
                        uiwait(msgbox(str, 'Error','error'));
                           disp(ME.message);

    disp('Detalles del error:');
    disp(ME.stack);
     for i = 1:length(ME.stack)
        disp(['Archivo: ', ME.stack(i).file]);
        disp(['Nombre: ', ME.stack(i).name]);
        disp(['Línea: ', num2str(ME.stack(i).line)]);
        disp('---');
    end
                    end
            
            
                    str = 'Resuming data acquisition.';
                    uiwait(msgbox(str, 'Information','help'));
                    drawnow
                else
                    userData.counterGesture = 1;
                end
                handles.msjText.String = '';
            
                % only enabling buttons
                handles.grabarButton.Enable = 'on';
                handles.repetirButton.Visible = 'on';
                drawnow
            else
                handles.msjText.String = {'NOT VALID DATA', msjPrint};
            end
        end

        % Menu selected function: fistGesture
        function fistGesture_Callback(app, event)
            % --------------------------------------------------------------------
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to fistGesture (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            playGif_App('fist');
        end

        % Menu selected function: forwardG
        function forwardG_Callback(app, event)
            % --------------------------------------------------------------------
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to forwardG (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            playGif_App('forward');
        end

        % Menu selected function: gForceFigure
        function gForceFigure_Callback(app, event)
            % --------------------------------------------------------------------
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to gForceFigure (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            app = imagenGForce_App();
        end

        % Button pushed function: grabarButton
        function grabarButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            %% disable GUI  clear EMG axes
            drawnow
            handles.repetirButton.Enable = 'off';
            handles.grabarButton.Enable = 'off';
            clearEMG(handles);
            
            %-% Load data
            global userData
            userData = loadUserData(userData);
            % repetitionCount = userData.counterRepetition;
            
            %-% Wait bar
            timeRep = userData.extraInfo.timePerRepetition;
            nameGesture = userData.gestures.classes{userData.counterGesture};
            transitionTime = getStartingTime(nameGesture, timeRep);
            
            myWaitbarTimer = timerWaitbar(handles, transitionTime, userData);
            userData.lastTransitionTime = transitionTime;
            drawnow
            start(myWaitbarTimer);
            drawnow
            
            %-% Save data
            global errorInData
            errorInData = []; % global var that is updated in timerfunc
            myTimerColletion = timerCollection(userData);
            drawnow
            a = tic;
            start(myTimerColletion);
            
            % wait(myTimerColletion); % gives error!
            while isempty(errorInData)
                % waiting 2 times the rep, or
            
                if toc(a) > 1.5*userData.extraInfo.timePerRepetition %2*userData.extraInfo.timePerRepetition
                    % too long, did not return, error!
                    errorInData = true;
                end
            
                drawnow
            end
            
            %-% Delete timers
            stop(myWaitbarTimer);
            stop(myTimerColletion);
            delete(myTimerColletion);
            delete(myWaitbarTimer);
            drawnow;
            
            %-% reiniciar waitbar!
            moverWaitbar(handles, 0, 0);
            
            if errorInData
                uiwait(errordlg({'No data received!'
                    ''
                    'Try recording again.'
                    ''
                    'If the error keeps appearing, try any of the following:'
                    '   1. Restart the device'
                    '   2. Check the battery'
                    '   3. Unplug and plug the dongle USB'
                    '   4. Restart matlab'}, 'ERROR!', 'modal'));
            
                handles.repetirButton.Enable = 'on';
                handles.grabarButton.Enable = 'off';
                drawnow
                return;
            end
            %-% show current emgs! depends on counters!
            repetirFlag = plotEMGSaved(handles, userData, transitionTime);
            
            %-% Grabar
            saveUserData(); % usa la variable global
            drawnow
            
            %-% Update counters && load image
            finishTraining = increaseIndicesAndLoadImage(handles);
            
            % flag...
            if finishTraining
                combineUserData(userData);
                drawnow
                finTraining(handles);
                return;
            end
            
            if repetirFlag
                % debe repetir
                uiwait(warndlg({'Few data received, please record again.' ...
                    'Possible reasons are:'...
                    '    1. Low battery,'...
                    '    2. More than 1 devices trying to connect.'...
                    'In the case this error persists, please restart the system.'}, ...
                    'Transmission error', 'modal'));
            
                handles.repetirButton.Enable = 'on';
                handles.grabarButton.Enable = 'off';
            
            else
                % normal continuation
                handles.repetirButton.Enable = 'on';
                handles.grabarButton.Enable = 'on';
            end
            drawnow
        end

        % Menu selected function: leftG
        function leftG_Callback(app, event)
            % --------------------------------------------------------------------
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to leftG (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            playGif_App('left');
        end

        % Close request function: lienzoFig
        function lienzoFig_CloseRequestFcn(app, event)
            % --- Executes when user attempts to close lienzoFig.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to lienzoFig (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: delete(hObject) closes the figure
            cleaningTimers();
            drawnow
            delete(gcf);
        end

        % Menu selected function: myoDiagnostics
        function myoDiagnostics_Callback(app, event)
            % --------------------------------------------------------------------
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to myoDiagnostics (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            str = {'Make sure USB dongle is connected to the PC'};
            uiwait(msgbox(str, 'Alert', 'help'));
            web('http://diagnostics.myo.com/');
        end

        % Menu selected function: myoFigure
        function myoFigure_Callback(app, event)
            % --------------------------------------------------------------------
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to myoFigure (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            app = imagenMyo_App();
        end

        % Value changed function: ocupacionText
        function ocupacionText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % Obtener el índice de la opción seleccionada en el popupmenu
                index = get(hObject, 'Value');
            
                % Obtener el texto de la opción seleccionada
                ocupacion = get(hObject, 'String');
            
                % Extraer la opción seleccionada usando el índice
                selectedOcupacion = ocupacion{index};

                % Guardar la ocupación seleccionada en handles para uso futuro
                handles.selectedOcupacion = selectedOcupacion;
            
                % Actualizar la estructura handles
                guidata(hObject, handles);
        end

        % Menu selected function: openGesture
        function openGesture_Callback(app, event)
            % --------------------------------------------------------------------
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to openGesture (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            playGif_App('open');
        end

        % Value changed function: perimetroText
        function perimetroText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            perimetro = str2double(handles.perimetroText.String);
            if ~isnumeric(perimetro)
                handles.perimetroText.String = '';
                errordlg('Must be number', 'Error', 'modal')
            end
        end

        % Menu selected function: relaxGesture
        function relaxGesture_Callback(app, event)
            % --------------------------------------------------------------------
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to relaxGesture (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            playGif_App('relax');
        end

        % Button pushed function: repetirButton
        function repetirButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            pregunta = questdlg('Are you sure you want to repeat the last recording?', ...
                'CONFIRM','YES', 'NO', 'YES');
            
            if isequal(pregunta,'YES')
                decreaseIndicesAndLoadImage(handles);
            end
        end

        % Button pushed function: restaurarButton
        function restaurarButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            %%
            global isValidRestaurar userData % myoObject
            bloquearGUI(handles);
            handles.msjText.String = {'Waiting to resume from restauration point'
                'Please, wait...'};
            drawnow

            app.RecuperateMenu.Enable = 'off';

            restaurar = restaurar_App()
            uiwait(restaurar.figure1); % busca usuario y actualiza punto de grabado!
            drawnow
            disp(userData)
            if isValidRestaurar
                setUserDataFromRestauration(handles,userData);
            
                % saveUserData(); % grabando dos veces!
                %-% Dibujar
                countGesture = userData.counterGesture;
                repXClass = userData.gestures.repXClass;
                handles.repeticionesText.String = [num2str(userData.counterRepetition) '/' num2str(repXClass(countGesture))];
            
                currentGesture = userData.gestures.classes{userData.counterGesture};
                handles.tituloGestoText.String = currentGesture;
                % dibujarImagen(handles, currentGesture);
                showGestureGif(handles, currentGesture);
                drawnow
                handles.grabarButton.Enable = 'on';
                handles.repetirButton.Visible = 'on';
                drawnow
            else
                handles.grabarButton.Enable = 'off';
                handles.repetirButton.Enable = 'off';
            
                handles.empezarEntrenamientoButton.Enable = 'on';
                handles.restaurarButton.Enable = 'on';
            end
        end

        % Menu selected function: rightG
        function rightG_Callback(app, event)
            % --------------------------------------------------------------------
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to rightG (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            playGif_App('right');
        end

        % Button pushed function: surveyButton
        function surveyButton_Callback(app, event)
            % --- Executes on button press in surveyButton.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to surveyButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            try
                survey;
            catch ME
                errordlg(['Error opening application: ' ME.message], 'Application Error');
            end
        end

        % Menu selected function: syncGesture
        function syncGesture_Callback(app, event)
            % --------------------------------------------------------------------
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to syncGesture (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            %playGif('waveOut');
            playGif_App('sync');
        end

        % Menu selected function: upG
        function upG_Callback(app, event)
            % --------------------------------------------------------------------
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to upG (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            playGif_App('up');
        end

        % Menu selected function: waveInGesture
        function waveInGesture_Callback(app, event)
            % --------------------------------------------------------------------
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to waveInGesture (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            playGif_App('waveIn');
        end

        % Menu selected function: waveOutGesture
        function waveOutGesture_Callback(app, event)
            % --------------------------------------------------------------------
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to waveOutGesture (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            playGif_App('waveOut');
        end

        % Menu selected function: RecuperateMenu
        function RecuperateMenuSelected(app, event)
           app.RecuperatePanel.Position = [252 115 260 123];
           app.RecuperatePanel.Visible = "on";

        end

        % Button pushed function: AcceptButton
        function AcceptButtonPushed(app, event)

          user = app.NameEditField.Value;
          

            filepath = fullfile('.','data', user, 'userData.mat');
            if exist(filepath, 'file') == 2
                % Cargar el archivo si existe
                userD = load(filepath);
                userInfo= userD.userData.userInfo;


                %Cargar respuestas
                app.nameUserText.Value = userInfo.username;
                app.edadText.Value=num2str(userInfo.age);

                if strcmp(userInfo.gender, 'Man')
                    app.hombreRadio.Value = true;
                else
                    app.mujerRadio.Value = true;
                end

                app.spoUserText.Value = userInfo.oxygenSaturation;
                app.bloodPressureUserText.Value = userInfo.bloodPressure;
                app.TempUserText.Value = userInfo.temperature ;

                app.wtUserText.Value = userInfo.weight;
                app.htUserText.Value = userInfo.height;

                if strcmp(userInfo.handedness, 'Right')
                    app.diestroRadio.Value = true;
                else 
                    app.zurdoRadio.Value = true;
                end
                    
                app.perimetroText.Value = num2str(userInfo.armPerimeter);
                app.codMyoText.Value = num2str(userInfo.fromElbowToMyo);
                app.cubCodoText.Value = num2str(userInfo.fromElbowToUlna);

                 app.ocupacionText.Value = userInfo.occupation;

                 % quitar Cuadro
                 global userData;
                 userData.userInfo.answers = userInfo.answers;

             
            app.RecuperatePanel.Position = [3333 115 260 123];
            app.NameEditField.Value = '';
           app.RecuperatePanel.Visible = "off";
            else
                % Mostrar un mensaje de error si el archivo no se encuentra
                uialert(app.lienzoFig, 'Archivo no encontrado', 'Error', 'Icon', 'error');
                app.RecuperatePanel.Position = [3333 115 260 123];
            app.NameEditField.Value = '';
           app.RecuperatePanel.Visible = "off";
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.lienzoFig = uifigure('Visible', 'off');
            app.lienzoFig.Name = 'DATA ACQUISITION INTERFACE';
            app.lienzoFig.CloseRequestFcn = createCallbackFcn(app, @lienzoFig_CloseRequestFcn, true);
            app.lienzoFig.Tag = 'lienzoFig';
            app.instructionsMenu = uimenu(app.lienzoFig);
            app.instructionsMenu.Text = 'Instructions';
            app.instructionsMenu.Tag = 'instructionsMenu';
            app.dataAdquisitionInstructions = uimenu(app.instructionsMenu);
            app.dataAdquisitionInstructions.MenuSelectedFcn = createCallbackFcn(app, @dataAdquisitionInstructions_Callback, true);
            app.dataAdquisitionInstructions.Text = 'Data acquisition';
            app.dataAdquisitionInstructions.Tag = 'dataAdquisitionInstructions';
            app.myoMenu = uimenu(app.lienzoFig);
            app.myoMenu.Separator = 'on';
            app.myoMenu.Text = 'Myo Armband';
            app.myoMenu.Tag = 'myoMenu';
            app.myoFigure = uimenu(app.myoMenu);
            app.myoFigure.MenuSelectedFcn = createCallbackFcn(app, @myoFigure_Callback, true);
            app.myoFigure.Text = 'Myo image';
            app.myoFigure.Tag = 'myoFigure';
            app.myoDiagnostics = uimenu(app.myoMenu);
            app.myoDiagnostics.MenuSelectedFcn = createCallbackFcn(app, @myoDiagnostics_Callback, true);
            app.myoDiagnostics.Text = 'Myo diagnostics (web)';
            app.myoDiagnostics.Tag = 'myoDiagnostics';
            app.gForceMenu = uimenu(app.lienzoFig);
            app.gForceMenu.Text = 'GForce Pro';
            app.gForceMenu.Tag = 'gForceMenu';
            app.gForceFigure = uimenu(app.gForceMenu);
            app.gForceFigure.MenuSelectedFcn = createCallbackFcn(app, @gForceFigure_Callback, true);
            app.gForceFigure.Text = 'Location';
            app.gForceFigure.Tag = 'gForceFigure';
            app.videosGestures = uimenu(app.lienzoFig);
            app.videosGestures.Separator = 'on';
            app.videosGestures.Text = 'Gestures videos';
            app.videosGestures.Tag = 'videosGestures';
            app.syncGesture = uimenu(app.videosGestures);
            app.syncGesture.MenuSelectedFcn = createCallbackFcn(app, @syncGesture_Callback, true);
            app.syncGesture.Text = 'Sync';
            app.syncGesture.Tag = 'syncGesture';
            app.relaxGesture = uimenu(app.videosGestures);
            app.relaxGesture.MenuSelectedFcn = createCallbackFcn(app, @relaxGesture_Callback, true);
            app.relaxGesture.Text = 'Relax';
            app.relaxGesture.Tag = 'relaxGesture';
            app.fistGesture = uimenu(app.videosGestures);
            app.fistGesture.MenuSelectedFcn = createCallbackFcn(app, @fistGesture_Callback, true);
            app.fistGesture.Text = 'Fist';
            app.fistGesture.Tag = 'fistGesture';
            app.openGesture = uimenu(app.videosGestures);
            app.openGesture.MenuSelectedFcn = createCallbackFcn(app, @openGesture_Callback, true);
            app.openGesture.Text = 'Open';
            app.openGesture.Tag = 'openGesture';
            app.waveInGesture = uimenu(app.videosGestures);
            app.waveInGesture.MenuSelectedFcn = createCallbackFcn(app, @waveInGesture_Callback, true);
            app.waveInGesture.Text = 'Wave In';
            app.waveInGesture.Tag = 'waveInGesture';
            app.waveOutGesture = uimenu(app.videosGestures);
            app.waveOutGesture.MenuSelectedFcn = createCallbackFcn(app, @waveOutGesture_Callback, true);
            app.waveOutGesture.Text = 'Wave Out';
            app.waveOutGesture.Tag = 'waveOutGesture';
            app.upG = uimenu(app.videosGestures);
            app.upG.MenuSelectedFcn = createCallbackFcn(app, @upG_Callback, true);
            app.upG.Text = 'Up';
            app.upG.Tag = 'upG';
            app.downG = uimenu(app.videosGestures);
            app.downG.MenuSelectedFcn = createCallbackFcn(app, @downG_Callback, true);
            app.downG.Text = 'Down';
            app.downG.Tag = 'downG';
            app.leftG = uimenu(app.videosGestures);
            app.leftG.MenuSelectedFcn = createCallbackFcn(app, @leftG_Callback, true);
            app.leftG.Text = 'Left';
            app.leftG.Tag = 'leftG';
            app.rightG = uimenu(app.videosGestures);
            app.rightG.MenuSelectedFcn = createCallbackFcn(app, @rightG_Callback, true);
            app.rightG.Text = 'Right';
            app.rightG.Tag = 'rightG';
            app.forwardG = uimenu(app.videosGestures);
            app.forwardG.MenuSelectedFcn = createCallbackFcn(app, @forwardG_Callback, true);
            app.forwardG.Text = 'Forward';
            app.forwardG.Tag = 'forwardG';
            app.backG = uimenu(app.videosGestures);
            app.backG.MenuSelectedFcn = createCallbackFcn(app, @backG_Callback, true);
            app.backG.Text = 'Backward';
            app.backG.Tag = 'backG';
            app.acercaDe = uimenu(app.lienzoFig);
            app.acercaDe.MenuSelectedFcn = createCallbackFcn(app, @acercaDe_Callback, true);
            app.acercaDe.Text = 'About...';
            app.acercaDe.Tag = 'acercaDe';
            app.contactInformation = uimenu(app.lienzoFig);
            app.contactInformation.MenuSelectedFcn = createCallbackFcn(app, @contactInformation_Callback, true);
            app.contactInformation.Text = 'Contact';
            app.contactInformation.Tag = 'contactInformation';
            app.HelpMenu = uimenu(app.lienzoFig);
            app.HelpMenu.Text = 'Help';
            app.RecuperateMenu = uimenu(app.HelpMenu);
            app.RecuperateMenu.MenuSelectedFcn = createCallbackFcn(app, @RecuperateMenuSelected, true);
            app.RecuperateMenu.Text = 'Recuperate';
            app.Toolbar = uitoolbar(app.lienzoFig);
            app.PushTool = uipushtool(app.Toolbar);
            app.uipanel18 = uipanel(app.lienzoFig);
            app.uipanel18.Title = 'Device connection';
            app.uipanel18.Tag = 'uipanel18';
            app.connectLedAxes = uiaxes(app.uipanel18);
            app.connectLedAxes.Tag = 'connectLedAxes';
            app.connectMyo_Button = uibutton(app.uipanel18, 'push');
            app.connectMyo_Button.ButtonPushedFcn = createCallbackFcn(app, @connectMyo_Button_Callback, true);
            app.connectMyo_Button.Tag = 'connectMyo_Button';
            app.connectMyo_Button.Text = 'MYO';
            app.text23 = uilabel(app.uipanel18);
            app.text23.Tag = 'text23';
            app.text23.Text = 'Connection:';
            app.connectGForce_Button = uibutton(app.uipanel18, 'push');
            app.connectGForce_Button.ButtonPushedFcn = createCallbackFcn(app, @connectGForce_Button_Callback, true);
            app.connectGForce_Button.Tag = 'connectGForce_Button';
            app.connectGForce_Button.Text = 'GFORCE';
            app.text70 = uilabel(app.uipanel18);
            app.text70.Tag = 'text70';
            app.text70.Text = 'Battery:';
            app.bat_txt = uilabel(app.uipanel18);
            app.bat_txt.Tag = 'bat_txt';
            app.bat_txt.Text = '--';
            app.configuracionPanel = uipanel(app.lienzoFig);
            app.configuracionPanel.Title = 'User information';
            app.configuracionPanel.Tag = 'configuracionPanel';
            app.nameUserText = uieditfield(app.configuracionPanel, 'text');
            app.nameUserText.Tag = 'nameUserText';
            app.nameUserText.Value = 'user#';
            app.text21 = uilabel(app.configuracionPanel);
            app.text21.Tag = 'text21';
            app.text21.Text = 'Name';
            app.text24 = uilabel(app.configuracionPanel);
            app.text24.Tag = 'text24';
            app.text24.Text = 'Age';
            app.edadText = uieditfield(app.configuracionPanel, 'text');
            app.edadText.ValueChangedFcn = createCallbackFcn(app, @edadText_Callback, true);
            app.edadText.Tag = 'edadText';
            app.edadText.Value = '20';
            app.handednessGroup = uibuttongroup(app.configuracionPanel);
            app.handednessGroup.Title = 'Handedness';
            app.handednessGroup.Tag = 'handednessGroup';
            app.diestroRadio = uiradiobutton(app.handednessGroup);
            app.diestroRadio.Tag = 'diestroRadio';
            app.diestroRadio.Text = 'Right';
            app.diestroRadio.Value = true;
            app.zurdoRadio = uiradiobutton(app.handednessGroup);
            app.zurdoRadio.Tag = 'zurdoRadio';
            app.zurdoRadio.Text = 'Left';
            app.text55 = uilabel(app.configuracionPanel);
            app.text55.Tag = 'text55';
            app.text55.Text = 'Occupation';
            app.ocupacionText = uidropdown(app.configuracionPanel);
            app.ocupacionText.Items = {'No Selected', 'Student', 'Teacher', 'Student PhD', 'Administrative', 'Secretary', 'Researcher Laboratorist', 'Analista Bachelor in Physiotherapy', 'Homemaker', 'Professional Driver', 'Mechanical Engineer'};
            app.ocupacionText.ValueChangedFcn = createCallbackFcn(app, @ocupacionText_Callback, true);
            app.ocupacionText.Tag = 'ocupacionText';
            app.ocupacionText.Value = 'Student';
            app.genderGroup = uibuttongroup(app.configuracionPanel);
            app.genderGroup.Title = 'Gender';
            app.genderGroup.Tag = 'genderGroup';
            app.hombreRadio = uiradiobutton(app.genderGroup);
            app.hombreRadio.Tag = 'hombreRadio';
            app.hombreRadio.Text = 'Man';
            app.hombreRadio.Value = true;
            app.mujerRadio = uiradiobutton(app.genderGroup);
            app.mujerRadio.Tag = 'mujerRadio';
            app.mujerRadio.Text = 'Woman';
            app.text73 = uilabel(app.configuracionPanel);
            app.text73.Tag = 'text73';
            app.text73.Text = 'Temp (C)';
            app.TempUserText = uieditfield(app.configuracionPanel, 'text');
            app.TempUserText.Tag = 'TempUserText';
            app.TempUserText.Value = '36.5';
            app.text74 = uilabel(app.configuracionPanel);
            app.text74.Tag = 'text74';
            app.text74.Text = 'BP mm/HG';
            app.bloodPressureUserText = uieditfield(app.configuracionPanel, 'text');
            app.bloodPressureUserText.Tag = 'bloodPressureUserText';
            app.bloodPressureUserText.Value = '120/80';
            app.text75 = uilabel(app.configuracionPanel);
            app.text75.Tag = 'text75';
            app.text75.Text = 'SpO2';
            app.spoUserText = uieditfield(app.configuracionPanel, 'text');
            app.spoUserText.Tag = 'spoUserText';
            app.spoUserText.Value = '95';
            app.text76 = uilabel(app.configuracionPanel);
            app.text76.Tag = 'text76';
            app.text76.Text = 'Wt (Kg)';
            app.wtUserText = uieditfield(app.configuracionPanel, 'text');
            app.wtUserText.Tag = 'wtUserText';
            app.wtUserText.Value = '65';
            app.text77 = uilabel(app.configuracionPanel);
            app.text77.Tag = 'text77';
            app.text77.Text = 'Ht (cm)';
            app.htUserText = uieditfield(app.configuracionPanel, 'text');
            app.htUserText.Tag = 'htUserText';
            app.htUserText.Value = '160';
            app.surveyButton = uibutton(app.configuracionPanel, 'push');
            app.surveyButton.ButtonPushedFcn = createCallbackFcn(app, @surveyButton_Callback, true);
            app.surveyButton.Tag = 'surveyButton';
            app.surveyButton.Text = 'Survey';
            app.uipanel2 = uipanel(app.lienzoFig);
            app.uipanel2.Title = 'Device signals';
            app.uipanel2.Tag = 'uipanel2';
            app.emg1Axes = uiaxes(app.uipanel2);
            app.emg1Axes.YLim = [-1 1];
            app.emg1Axes.ALim = [0 1];
            app.emg1Axes.Tag = 'emg1Axes';
            app.emg8Axes = uiaxes(app.uipanel2);
            app.emg8Axes.YLim = [-1 1];
            app.emg8Axes.Tag = 'emg8Axes';
            app.emg7Axes = uiaxes(app.uipanel2);
            app.emg7Axes.YLim = [-1 1];
            app.emg7Axes.Tag = 'emg7Axes';
            app.emg6Axes = uiaxes(app.uipanel2);
            app.emg6Axes.YLim = [-1 1];
            app.emg6Axes.Tag = 'emg6Axes';
            app.emg2Axes = uiaxes(app.uipanel2);
            app.emg2Axes.YLim = [-1 1];
            app.emg2Axes.Tag = 'emg2Axes';
            app.emg3Axes = uiaxes(app.uipanel2);
            app.emg3Axes.YLim = [-1 1];
            app.emg3Axes.Tag = 'emg3Axes';
            app.emg4Axes = uiaxes(app.uipanel2);
            app.emg4Axes.YLim = [-1 1];
            app.emg4Axes.Tag = 'emg4Axes';
            app.emg5Axes = uiaxes(app.uipanel2);
            app.emg5Axes.YLim = [-1 1];
            app.emg5Axes.Tag = 'emg5Axes';
            app.rotAxes = uiaxes(app.uipanel2);
            app.rotAxes.YLim = [-1 1];
            app.rotAxes.Tag = 'rotAxes';
            app.text53 = uilabel(app.uipanel2);
            app.text53.Tag = 'text53';
            app.text53.Text = 'Number of samples [Emg]:';
            app.muestrasText = uilabel(app.uipanel2);
            app.muestrasText.Tag = 'muestrasText';
            app.muestrasText.Text = '-';
            app.text58 = uilabel(app.uipanel2);
            app.text58.Tag = 'text58';
            app.text58.Text = '1';
            app.text59 = uilabel(app.uipanel2);
            app.text59.Tag = 'text59';
            app.text59.Text = '2';
            app.text61 = uilabel(app.uipanel2);
            app.text61.Tag = 'text61';
            app.text61.Text = '3';
            app.text62 = uilabel(app.uipanel2);
            app.text62.Tag = 'text62';
            app.text62.Text = '4';
            app.text63 = uilabel(app.uipanel2);
            app.text63.Tag = 'text63';
            app.text63.Text = '5';
            app.text64 = uilabel(app.uipanel2);
            app.text64.Tag = 'text64';
            app.text64.Text = '6';
            app.text65 = uilabel(app.uipanel2);
            app.text65.Tag = 'text65';
            app.text65.Text = '7';
            app.text66 = uilabel(app.uipanel2);
            app.text66.Tag = 'text66';
            app.text66.Text = '8';
            app.text68 = uilabel(app.uipanel2);
            app.text68.Tag = 'text68';
            app.text68.Text = '[Quaternions]:';
            app.muestrasQuat_txt = uilabel(app.uipanel2);
            app.muestrasQuat_txt.Tag = 'muestrasQuat_txt';
            app.muestrasQuat_txt.Text = '-';
            app.brazoPanel = uipanel(app.lienzoFig);
            app.brazoPanel.Title = 'Device location [cm]';
            app.brazoPanel.Tag = 'brazoPanel';
            app.perimetroText = uieditfield(app.brazoPanel, 'text');
            app.perimetroText.ValueChangedFcn = createCallbackFcn(app, @perimetroText_Callback, true);
            app.perimetroText.Tag = 'perimetroText';
            app.perimetroText.Value = '25';
            app.text26 = uilabel(app.brazoPanel);
            app.text26.Tag = 'text26';
            app.text26.Text = 'Forearm circumference';
            app.cubCodoText = uieditfield(app.brazoPanel, 'text');
            app.cubCodoText.ValueChangedFcn = createCallbackFcn(app, @cubCodoText_Callback, true);
            app.cubCodoText.Tag = 'cubCodoText';
            app.cubCodoText.Value = '25';
            app.codMyoText = uieditfield(app.brazoPanel, 'text');
            app.codMyoText.ValueChangedFcn = createCallbackFcn(app, @codMyoText_Callback, true);
            app.codMyoText.Tag = 'codMyoText';
            app.codMyoText.Value = '25';
            app.text29 = uilabel(app.brazoPanel);
            app.text29.Tag = 'text29';
            app.text29.Text = {'Min. distance'; 'Elbow-device'; ''};
            app.text28 = uilabel(app.brazoPanel);
            app.text28.Tag = 'text28';
            app.text28.Text = {'Arm length'; '(elbow - wrist)'};
            app.uipanel5 = uipanel(app.lienzoFig);
            app.uipanel5.Title = 'Parameters';
            app.uipanel5.Tag = 'uipanel5';
            app.numRepText = uieditfield(app.uipanel5, 'text');
            app.numRepText.Tag = 'numRepText';
            app.numRepText.Value = '50';
            app.numRepRelaxText = uieditfield(app.uipanel5, 'text');
            app.numRepRelaxText.Tag = 'numRepRelaxText';
            app.numRepRelaxText.Value = '50';
            app.text13 = uilabel(app.uipanel5);
            app.text13.Tag = 'text13';
            app.text13.Text = 'relax samples';
            app.timeGestureText = uieditfield(app.uipanel5, 'text');
            app.timeGestureText.Tag = 'timeGestureText';
            app.timeGestureText.Value = '5';
            app.text18 = uilabel(app.uipanel5);
            app.text18.Tag = 'text18';
            app.text18.Text = 'Recording time [seconds]';
            app.text36 = uilabel(app.uipanel5);
            app.text36.Tag = 'text36';
            app.text36.Text = 'Samples per gesture';
            app.myoPanel = uibuttongroup(app.lienzoFig);
            app.myoPanel.Title = 'Device';
            app.myoPanel.Tag = 'myoPanel';
            app.listOfMyos = uidropdown(app.myoPanel);
            app.listOfMyos.Items = {'Myo-?', 'Myo-?', 'Myo-?', 'GForce-?', 'GForce-?', 'GForce-?'};
            app.listOfMyos.Tag = 'listOfMyos';
            app.listOfMyos.Value = 'Myo-?';
            app.uipanel6 = uipanel(app.lienzoFig);
            app.uipanel6.Tag = 'uipanel6';
            app.lesionCheck = uicheckbox(app.uipanel6);
            app.lesionCheck.Tag = 'lesionCheck';
            app.lesionCheck.Text = 'Had suffer some arm damage?';
            app.uipanel17 = uipanel(app.lienzoFig);
            app.uipanel17.Tag = 'uipanel17';
            app.waitbarAxes = uiaxes(app.uipanel17);
            app.waitbarAxes.XLim = [0 1];
            app.waitbarAxes.YLim = [0 1];
            app.waitbarAxes.Tag = 'waitbarAxes';
            app.gestoAxes = uiaxes(app.uipanel17);
            app.gestoAxes.Tag = 'gestoAxes';
            app.uipanel16 = uipanel(app.uipanel17);
            app.uipanel16.Title = 'Sample';
            app.uipanel16.Tag = 'uipanel16';
            app.grabarButton = uibutton(app.uipanel16, 'push');
            app.grabarButton.ButtonPushedFcn = createCallbackFcn(app, @grabarButton_Callback, true);
            app.grabarButton.Tag = 'grabarButton';
            app.grabarButton.Enable = 'off';
            app.grabarButton.Tooltip = 'Record a new sample.';
            app.grabarButton.Text = 'RECORD';
            app.repetirButton = uibutton(app.uipanel16, 'push');
            app.repetirButton.ButtonPushedFcn = createCallbackFcn(app, @repetirButton_Callback, true);
            app.repetirButton.Tag = 'repetirButton';
            app.repetirButton.Enable = 'off';
            app.repetirButton.Tooltip = 'Problems in the last recording? Repeat it!';
            app.repetirButton.Text = 'REPEAT';
            app.uipanel14 = uipanel(app.uipanel17);
            app.uipanel14.Title = 'Acquisition';
            app.uipanel14.Tag = 'uipanel14';
            app.empezarEntrenamientoButton = uibutton(app.uipanel14, 'push');
            app.empezarEntrenamientoButton.ButtonPushedFcn = createCallbackFcn(app, @empezarEntrenamientoButton_Callback, true);
            app.empezarEntrenamientoButton.Tag = 'empezarEntrenamientoButton';
            app.empezarEntrenamientoButton.Enable = 'off';
            app.empezarEntrenamientoButton.Tooltip = 'Start a new Data Acquisition session.';
            app.empezarEntrenamientoButton.Text = 'New';
            app.restaurarButton = uibutton(app.uipanel14, 'push');
            app.restaurarButton.ButtonPushedFcn = createCallbackFcn(app, @restaurarButton_Callback, true);
            app.restaurarButton.Tag = 'restaurarButton';
            app.restaurarButton.Enable = 'off';
            app.restaurarButton.Tooltip = 'Resume the Data Acquisition from a previous incompleted session.';
            app.restaurarButton.Text = 'Resume';
            app.repeticionesText = uilabel(app.uipanel17);
            app.repeticionesText.Tag = 'repeticionesText';
            app.repeticionesText.Text = '/';
            app.tituloGestoText = uilabel(app.uipanel17);
            app.tituloGestoText.Tag = 'tituloGestoText';
            app.tituloGestoText.Text = 'gesture';
            app.msjText = uilabel(app.uipanel17);
            app.msjText.Tag = 'msjText';
            app.msjText.Text = 'CONNECT YOUR PREFERRED DEVICE WITH MATLAB!';
            app.bienvenidoText = uilabel(app.uipanel17);
            app.bienvenidoText.Tag = 'bienvenidoText';
            app.bienvenidoText.Text = 'Welcome';
            app.RecuperatePanel = uipanel(app.uipanel17);
            app.RecuperatePanel.Title = 'Recuperate';
            app.NameEditFieldLabel = uilabel(app.RecuperatePanel);
            app.NameEditFieldLabel.Text = 'Name';
            app.NameEditField = uieditfield(app.RecuperatePanel, 'text');
            app.NameEditField.Value = 'user#';
            app.NamesuserDataLabel = uilabel(app.RecuperatePanel);
            app.NamesuserDataLabel.Text = 'Name''s userData';
            app.AcceptButton = uibutton(app.RecuperatePanel, 'push');
            app.AcceptButton.ButtonPushedFcn = createCallbackFcn(app, @AcceptButtonPushed, true);
            app.AcceptButton.Text = 'Accept';
            app.uipanel9 = uipanel(app.lienzoFig);
            app.uipanel9.Tag = 'uipanel9';
            app.buhoAxes = uiaxes(app.uipanel9);
            app.buhoAxes.Tag = 'buhoAxes';
            app.escudoAxes = uiaxes(app.uipanel9);
            app.escudoAxes.Tag = 'escudoAxes';
            app.text44 = uilabel(app.uipanel9);
            app.text44.Tag = 'text44';
            app.text44.Text = 'Artificial Intelligence and Computer Vision Research Lab';
            app.text45 = uilabel(app.uipanel9);
            app.text45.Tag = 'text45';
            app.text45.Text = 'Hand Gesture Recognition';
            app.text46 = uilabel(app.uipanel9);
            app.text46.Tag = 'text46';
            app.text46.Text = 'ESCUELA POLITÉCNICA NACIONAL';
            app.txt_version = uilabel(app.uipanel9);
            app.txt_version.Tag = 'txt_version';
            app.txt_version.Text = 'ver: ??';
            % Show the figure after all components are created
            app.lienzoFig.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = entrenamiento_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.lienzoFig)

                % Execute the startup function
                runStartupFcn(app, @(app)entrenamiento_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.lienzoFig)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.lienzoFig)
        end
    end
end