classdef gestureSelector < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure      matlab.ui.Figure
        t_            matlab.ui.control.Label
        t_2           matlab.ui.control.Label
        but_continue  matlab.ui.control.Button
        check_sync    matlab.ui.control.CheckBox
        t_3           matlab.ui.control.Label
        radio_group   matlab.ui.container.ButtonGroup
        radio_g11     matlab.ui.control.RadioButton
        radio_g5      matlab.ui.control.RadioButton
    end


    properties (Access = public)
        configs % Description
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function init(app)
        movegui(app.UIFigure,'center')
        end

        % Button pushed function: but_continue
        function finish_gesture_selection(app, event)
            % callback of continue button
            radio_sel = app.radio_group.SelectedObject;

            %%
            if radio_sel.Tag == "G5"
                app.configs.gesture_set = GestureSet.G5;
            else
                app.configs.gesture_set = GestureSet.G11;
            end

            app.configs.include_sync = app.check_sync.Value;
            
            % must be closed outside
            %app.delete
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.radio_group = uibuttongroup(app.UIFigure);
            app.radio_group.Title = 'Gesture sets';
            app.radio_g5 = uiradiobutton(app.radio_group);
            app.radio_g5.Tag = 'G5';
            app.radio_g5.Text = 'G5 (wave in, wave out, fist, open, pinch)';
            app.radio_g11 = uiradiobutton(app.radio_group);
            app.radio_g11.Tag = 'G11';
            app.radio_g11.Text = 'G11 (G5 + forward, back, up, down, left, right)';
            app.radio_g11.Value = true;
            app.t_3 = uilabel(app.UIFigure);
            app.t_3.Text = 'Gesture selector';
            app.check_sync = uicheckbox(app.UIFigure);
            app.check_sync.Text = 'sync gesture';
            app.check_sync.Value = true;
            app.but_continue = uibutton(app.UIFigure, 'push');
            app.but_continue.ButtonPushedFcn = createCallbackFcn(app, @finish_gesture_selection, true);
            app.but_continue.Text = 'Save';
            app.t_2 = uilabel(app.UIFigure);
            app.t_2.Text = 'Choose the set of gesture you wish to record. ';
            app.t_ = uilabel(app.UIFigure);
            app.t_.Text = 'Choose if you wish to record the sync gesture.';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gestureSelector

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @init)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end