classdef imagenGForce_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1       matlab.ui.Figure
        cerrarButton  matlab.ui.control.Button
        text2         matlab.ui.control.Label
        myoImg        matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function imagenGForce_OpeningFcn(app, varargin)
            % --- Executes just before imagenGForce is made visible.
            % IMAGENGFORCE MATLAB code for imagenGForce.fig
%      IMAGENGFORCE, by itself, creates a new IMAGENGFORCE or raises the existing
%      singleton*.
%
%      H = IMAGENGFORCE returns the handle to a new IMAGENGFORCE or the handle to
%      the existing singleton*.
%
%      IMAGENGFORCE('CALLBACK',hObject,eventData,handles,...) calls the local
%      function named CALLBACK in IMAGENGFORCE.M with the given input arguments.
%
%      IMAGENGFORCE('Property','Value',...) creates a new IMAGENGFORCE or raises the
%      existing singleton*.  Starting from the left, property value pairs are
%      applied to the GUI before imagenGForce_OpeningFcn gets called.  An
%      unrecognized property name or invalid value makes property application
%      stop.  All inputs are passed to imagenGForce_OpeningFcn via varargin.

% Last Modified: v2.6 23-Sep-2024 14:00:00

            % Ensure that the app appears on screen when run
            movegui(app.figure1, 'onscreen');
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to imagenGForce (see VARARGIN)
            
            % Choose default command line output for imagenGForce
            handles.output = hObject;
            
            % Update handles structure
            guidata(hObject, handles);
            % UIWAIT makes imagenGForce wait for user response (see UIRESUME)
            % uiwait(handles.figure1);
            movegui(hObject,'center') % mostrando en el centro
            imgMyo = imread('images\gForce\gForce.jpg'); % leyendo imagen;
            imshow(imgMyo, 'Parent', handles.myoImg);
            
            
            % img2 = imread('images\gForce\howToGForce.png'); % leyendo imagen;
            % imshow(img2, 'Parent', handles.axesHowTo);
            
            
            drawnow
        end

        % Button pushed function: cerrarButton
        function cerrarButton_Callback(app, event)
            % --- Executes on button press in cerrarButton.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to cerrarButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            close(gcf);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'GForce Pro';
            app.figure1.Tag = 'figure1';
            app.myoImg = uiaxes(app.figure1);
            app.myoImg.Tag = 'myoImg';
            app.text2 = uilabel(app.figure1);
            app.text2.Tag = 'text2';
            app.text2.Text = 'GForce Pro';
            app.cerrarButton = uibutton(app.figure1, 'push');
            app.cerrarButton.ButtonPushedFcn = createCallbackFcn(app, @cerrarButton_Callback, true);
            app.cerrarButton.Tag = 'cerrarButton';
            app.cerrarButton.Text = 'CLOSE';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = imagenGForce_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)imagenGForce_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end