classdef listaRecolectoresDatos_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                   matlab.ui.Figure
        aceptarPushbutton         matlab.ui.control.Button
        uipanel1                  matlab.ui.container.Panel
        listaRecolectoresPopMenu  matlab.ui.control.DropDown
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function listaRecolectoresDatos_OpeningFcn(app, varargin)
            % LISTARECOLECTORESDATOS MATLAB code for listaRecolectoresDatos.fig
%      LISTARECOLECTORESDATOS, by itself, creates a new LISTARECOLECTORESDATOS or raises the existing
%      singleton*.
%
%      H = LISTARECOLECTORESDATOS returns the handle to a new LISTARECOLECTORESDATOS or the handle to
%      the existing singleton*.
%
%      LISTARECOLECTORESDATOS('CALLBACK',hObject,eventData,handles,...) calls the local
%      function named CALLBACK in LISTARECOLECTORESDATOS.M with the given input arguments.
%
%      LISTARECOLECTORESDATOS('Property','Value',...) creates a new LISTARECOLECTORESDATOS or raises the
%      existing singleton*.  Starting from the left, property value pairs are
%      applied to the GUI before listaRecolectoresDatos_OpeningFcn gets called.  An
%      unrecognized property name or invalid value makes property application
%      stop.  All inputs are passed to listaRecolectoresDatos_OpeningFcn via varargin.
%

% Edit the above text to modify the response to help listaRecolectoresDatos

% Last Modified: v1.8 23-Sep-2024 ‏‎14:00:00
            
            
            % --- Executes just before listaRecolectoresDatos is made visible.
            
            % Ensure that the app appears on screen when run
            movegui(app.figure1, 'onscreen');
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            

            set(handles.listaRecolectoresPopMenu, 'Value', 1);
            %- Choose default command line output for listaRecolectoresDatos
            handles.output = hObject;
            
            % Update handles structure
            guidata(hObject, handles);
            
            movegui(hObject,'center'); % mostrando en el centro
            global lista;
            [encabezado, lista, num] = crearListadeRecolectoresDeDatos();
            str = cell(num + 1, 1);
            str{1} = encabezado;
            for i = 2:(num + 1)
                str{i} = lista(i - 1).name;
            end
            % str{num + 2} = 'My name is not here';
            set(handles.listaRecolectoresPopMenu, 'String', str);
        end

        % Button pushed function: aceptarPushbutton
        function aceptarPushbutton_Callback(app, event)
            % --- Executes on button press in aceptarPushbutton.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to aceptarPushbutton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            global lista nombreRecolectorDeDatos;
            valor = get(handles.listaRecolectoresPopMenu, 'Value');
            if valor == 1 % No se selecciona un nombre
                str = {'Choose a name from the list'};
                uiwait(msgbox(str,'WARNING','warn'));
            else
                % popMenuList = get(handles.listaRecolectoresPopMenu, 'String');
                delete(gcf);
          
                % before to entrenamiento 
  
           
                nombreRecolectorDeDatos = lista(valor - 1);

                % qstring{1} = 'You selected:';
                % qstring{2} = '';
                % qstring{3} = upper(lista(valor - 1).name);
                % qstring{4} = '';
                % qstring{5} = '¿Is it right?';
                % choice = questdlg(qstring,'Data collector',...
                %     'Yes','No','Yes');
                %
                % if strcmpi(choice,'Yes')
                %     delete(gcf);
                %     entrenamiento();
                % end
                              global gesture_set_confs
            if configs("select_gesture")
                %------ select gestures
                app = gestureSelector();
                waitfor(app,'configs');
            
                gesture_set_confs = app.configs;
                app.delete
                clear app
            else
                % default values from configs,
                gesture_set_confs  = configs("gesture_setup");
            end
                 app = entrenamiento_App();
            
            end
        end

        % Close request function: figure1
        function figure1_CloseRequestFcn(app, event)
            % --- Executes when user attempts to close figure1.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to figure1 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: delete(hObject) closes the figure
            global nombreRecolectorDeDatos;
            
            qstring = 'Are you sure to leave this program?';
            choice = questdlg(qstring,'Exit',...
                'YES','NO','NO');
            if strcmpi(choice,'YES')
                nombreRecolectorDeDatos = [];
                delete(gcf);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'DATA RECOLECTOR';
            app.figure1.CloseRequestFcn = createCallbackFcn(app, @figure1_CloseRequestFcn, true);
            app.figure1.Tag = 'figure1';
            app.uipanel1 = uipanel(app.figure1);
            app.uipanel1.Title = 'RECORDER NAME';
            app.uipanel1.Tag = 'uipanel1';
            app.listaRecolectoresPopMenu = uidropdown(app.uipanel1);
            app.listaRecolectoresPopMenu.Items = {};
            app.listaRecolectoresPopMenu.Tag = 'listaRecolectoresPopMenu';
            app.listaRecolectoresPopMenu.Value = {};
            app.aceptarPushbutton = uibutton(app.figure1, 'push');
            app.aceptarPushbutton.ButtonPushedFcn = createCallbackFcn(app, @aceptarPushbutton_Callback, true);
            app.aceptarPushbutton.Tag = 'aceptarPushbutton';
            app.aceptarPushbutton.Text = 'ACCEPT';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = listaRecolectoresDatos_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)listaRecolectoresDatos_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end