classdef playGif_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1           matlab.ui.Figure
        closePushbutton   matlab.ui.control.Button
        replayPushbutton  matlab.ui.control.Button
        gestureName       matlab.ui.control.Label
        axes1             matlab.ui.control.UIAxes
    end

    % PLAYGIF MATLAB code for playGif.fig
%      PLAYGIF, by itself, creates a new PLAYGIF or raises the existing
%      singleton*.
%
%      H = PLAYGIF returns the handle to a new PLAYGIF or the handle to
%      the existing singleton*.
%
%      PLAYGIF('CALLBACK',hObject,eventData,handles,...) calls the local
%      function named CALLBACK in PLAYGIF.M with the given input arguments.
%
%      PLAYGIF('Property','Value',...) creates a new PLAYGIF or raises the
%      existing singleton*.  Starting from the left, property value pairs are
%      applied to the GUI before playGif_OpeningFcn gets called.  An
%      unrecognized property name or invalid value makes property application
%      stop.  All inputs are passed to playGif_OpeningFcn via varargin.
%
% Edit the above text to modify the response to help playGif

% Last Modified v2.6 23-Sep-2024 14:00:00
    methods (Access = private)
        function play(app, hObject, eventdata, handles)
            global filename;
            set(handles.gestureName, 'String', upper( filename ));
            axes(handles.axes1);
            % fullname = fullfile('gifs',[filename '.gif']);
            
            options = configs();
            fullname = [options.gifsPath filename '.gif'];
            [gifImage, cmap] = imread(fullname, 'Frames', 'all');
            gifDraw = imshow(gifImage(:,:,:,1));
            colormap(cmap)
            [rows, columns, numColorChannels, numImages] = size(gifImage);
            drawnow
            
            gifinfo = imfinfo(fullname);
            % the delayTime is in hundreds of second
            gifPeriod = mean([gifinfo.DelayTime])/100;
            % Despliega el gif en forma de video
            for k = 1 : numImages
                try
                    if get(handles.closePushbutton,'userdata') % stop condition
                        break;
                    end
                catch
                    break;
                end
                a = tic;
                gifDraw.CData = gifImage(:,:,:,k);
            
                %     thisFrame = gifImage(:,:,:,k);
                %     thisRGB = uint8(255 * ind2rgb(thisFrame, cmap));
                %        thisRGB = image(ind2rgb(thisFrame, cmap));
                %     imshow(thisRGB);
                while toc(a) < gifPeriod
                    drawnow;
                end
            end
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function playGif_OpeningFcn(app, varargin)
            % --- Executes just before playGif is made visible.

            % Ensure that the app appears on screen when run
            movegui(app.figure1, 'onscreen');
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            global filename;
             filename = varargin{1};

            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to playGif (see VARARGIN)
            
            % Choose default command line output for playGif
            handles.output = hObject;
            
            % Update handles structure
            guidata(hObject, handles);
            movegui(hObject,'center'); % mostrando en el centr
            imshow(ones(150,250,3), 'Parent', handles.axes1);
            play(app, hObject, eventdata, handles);
            drawnow;
        end

        % Button pushed function: closePushbutton
        function closePushbutton_Callback(app, event)
            % --- Executes on button press in closePushbutton.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to closePushbutton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            delete(gcf);
        end

        % Button pushed function: replayPushbutton
        function replayPushbutton_Callback(app, event)
            % --- Executes on button press in replayPushbutton.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to replayPushbutton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            play(app, hObject, eventdata, handles);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'GESTURES VIDEOS';
            app.figure1.Tag = 'figure1';
            app.axes1 = uiaxes(app.figure1);
            app.axes1.Tag = 'axes1';
            app.gestureName = uilabel(app.figure1);
            app.gestureName.Tag = 'gestureName';
            app.gestureName.Text = '';
            app.replayPushbutton = uibutton(app.figure1, 'push');
            app.replayPushbutton.ButtonPushedFcn = createCallbackFcn(app, @replayPushbutton_Callback, true);
            app.replayPushbutton.Tag = 'replayPushbutton';
            app.replayPushbutton.Text = 'REPEAT';
            app.closePushbutton = uibutton(app.figure1, 'push');
            app.closePushbutton.ButtonPushedFcn = createCallbackFcn(app, @closePushbutton_Callback, true);
            app.closePushbutton.Tag = 'closePushbutton';
            app.closePushbutton.Text = 'CLOSE';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = playGif_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)playGif_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end