classdef restaurar_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1         matlab.ui.Figure
        incompletoText  matlab.ui.control.Label
        text8           matlab.ui.control.Label
        text5           matlab.ui.control.Label
        repList         matlab.ui.control.ListBox
        text4           matlab.ui.control.Label
        text3           matlab.ui.control.Label
        gestosList      matlab.ui.control.ListBox
        text2           matlab.ui.control.Label
        okButton        matlab.ui.control.Button
        usuariosList    matlab.ui.control.ListBox
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function restaurar_OpeningFcn(app, varargin)
            % --- Executes just before restaurar is made visible.
            
            % Ensure that the app appears on screen when run
            movegui(app.figure1, 'onscreen');
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            %%
            handles.output = hObject;
            
            % Update handles structure
            guidata(hObject, handles);
            
            % loading users!
            usuarios = findingUsuarios();
            handles.usuariosList.String = usuarios;
            
            handles.usuariosList.Max = 2; % ?
            handles.usuariosList.Value = [];
            handles.okButton.Enable = 'off';
        end

        % Value changed function: gestosList
        function gestosList_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            %%
            global theSelected
            repXGestoRealizadas = theSelected.repXGestoRealizadas;
            
            %--% gesto elegido!
            choosenGesto = handles.gestosList.Value;
            theSelected.gesto = choosenGesto;
            
            %--% repeticiones que realizó tal usuario!
            repVector = strsplit(num2str(1:repXGestoRealizadas(choosenGesto)),' ');
            handles.repList.String = repVector;
            handles.repList.Max = 2;
            handles.repList.Value = [];
            handles.okButton.Enable = 'off';
            handles.repList.Visible = 'on';
        end

        % Button pushed function: okButton
        function okButton_Callback(app, event)
            % --- Executes on button press in okButton.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            %%
            global theSelected isValidRestaurar userData
            
            nameUser = theSelected.user;
            
            %---% load userData
            filepath = ['.\data\' nameUser '\'];
            loadedFile = load([ filepath 'userData.mat']);
            userData = loadedFile.userData;
            userData.counterGesture = theSelected.gesto;
            userData.counterRepetition = theSelected.choosenRep;
            
            % --- limiting reps
            userData = loadUserData(userData);
            
            nameGesture = userData.gestures.classes{userData.counterGesture};
            dataReps = ...
                userData.gestures.(nameGesture).data(1:userData.counterRepetition);
            
            % eliminatingOtherfiles(); % not implemented
            saveUserData(); % updating counters!
            userData.gestures.(nameGesture).data = dataReps; % ya que saveuser elimina las reps
            
            isValidRestaurar = true; % flag to tell whether restauration is valid
            clearvars -global -except isValidRestaurar ...
                userData myoObject deviceType gForceObject
            
            delete(gcf)
            drawnow
        end

        % Value changed function: repList
        function repList_Callback(app, event)
            % --- Executes on selection change in repList.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            %%
            global theSelected
            %--% gesto elegido!
            choosenGesto = handles.gestosList.Value;
            theSelected.gesto = choosenGesto;
            repXGestoRealizadas = theSelected.repXGestoRealizadas;
            
            %--% muestra elegida!
            if ~isempty(repXGestoRealizadas(choosenGesto))
                handles.okButton.Enable = 'on';
                choosenRep = handles.repList.Value;
                theSelected.choosenRep = choosenRep;
            end
        end

        % Value changed function: usuariosList
        function usuariosList_Callback(app, event)
            % --- Executes on selection change in usuariosList.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            %%
            global theSelected % info about the selected user
            %-- usuario elegido!
            choosenUsuario = handles.usuariosList.Value;
            choosenUsuario = handles.usuariosList.String{choosenUsuario};
            theSelected.user = choosenUsuario;
            
            %--- gestos que realizó tal usuario!
            [gestosRealizados, repXGestoRealizadas, ~, isDataComplete] = ...
                checkGestos(choosenUsuario);
            handles.gestosList.String = gestosRealizados; % poniendo gestos
            handles.gestosList.Visible = 'on';
            handles.gestosList.Max = 2; % ?
            handles.gestosList.Value = [];
            handles.okButton.Enable = 'off';
            
            theSelected.repXGestoRealizadas = repXGestoRealizadas;
            
            if isDataComplete
                handles.incompletoText.String = '';
            else
                handles.incompletoText.String = 'WARNING, USER WITH INCOMPLETE RECORDINGS';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'RESUME';
            app.figure1.Tag = 'figure1';
            app.usuariosList = uilistbox(app.figure1);
            app.usuariosList.Items = {};
            app.usuariosList.Multiselect = 'on';
            app.usuariosList.ValueChangedFcn = createCallbackFcn(app, @usuariosList_Callback, true);
            app.usuariosList.Tag = 'usuariosList';
            app.usuariosList.Value = {};
            app.okButton = uibutton(app.figure1, 'push');
            app.okButton.ButtonPushedFcn = createCallbackFcn(app, @okButton_Callback, true);
            app.okButton.Tag = 'okButton';
            app.okButton.Enable = 'off';
            app.okButton.Text = 'RESUME';
            app.text2 = uilabel(app.figure1);
            app.text2.Tag = 'text2';
            app.text2.Text = 'CHOOSE USER';
            app.gestosList = uilistbox(app.figure1);
            app.gestosList.Items = {'g1', 'g2', '3', '4             ', '5', '6', '7', '8', '9', '10', '11', '12'};
            app.gestosList.ValueChangedFcn = createCallbackFcn(app, @gestosList_Callback, true);
            app.gestosList.Tag = 'gestosList';
            app.gestosList.Visible = 'off';
            app.gestosList.Value = 'g1';
            app.text3 = uilabel(app.figure1);
            app.text3.Tag = 'text3';
            app.text3.Text = 'RESUME ACQUISITION';
            app.text4 = uilabel(app.figure1);
            app.text4.Tag = 'text4';
            app.text4.Text = 'CHOOSE GESTURE';
            app.repList = uilistbox(app.figure1);
            app.repList.Items = {'1', '2', '3', '4', '5', '56', '7', '8', '8', '3', ''};
            app.repList.ValueChangedFcn = createCallbackFcn(app, @repList_Callback, true);
            app.repList.Tag = 'repList';
            app.repList.Visible = 'off';
            app.repList.Value = '1';
            app.text5 = uilabel(app.figure1);
            app.text5.Tag = 'text5';
            app.text5.Text = 'CHOOSE SAMPLE';
            app.text8 = uilabel(app.figure1);
            app.text8.Tag = 'text8';
            app.text8.Text = {'Choose 1) user, 2) gesture, and 3) sample'; '                                                                                                '; '                                                                                                '; '                                                                                                '};
            app.incompletoText = uilabel(app.figure1);
            app.incompletoText.Tag = 'incompletoText';
            app.incompletoText.Text = '';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = restaurar_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)restaurar_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end