classdef sincronizacion_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1           matlab.ui.Figure
        sampleNumberText  matlab.ui.control.Label
        text14            matlab.ui.control.Label
        msjText           matlab.ui.control.Label
        replayPushbutton  matlab.ui.control.Button
        recordPushbutton  matlab.ui.control.Button
        text12            matlab.ui.control.Label
        uipanel1          matlab.ui.container.Panel
        text11            matlab.ui.control.Label
        text10            matlab.ui.control.Label
        text9             matlab.ui.control.Label
        text8             matlab.ui.control.Label
        text7             matlab.ui.control.Label
        text6             matlab.ui.control.Label
        text5             matlab.ui.control.Label
        text4             matlab.ui.control.Label
        numPointsText     matlab.ui.control.Label
        text2             matlab.ui.control.Label
        axes5             matlab.ui.control.UIAxes
        axes4             matlab.ui.control.UIAxes
        axes3             matlab.ui.control.UIAxes
        axes2             matlab.ui.control.UIAxes
        axes6             matlab.ui.control.UIAxes
        axes7             matlab.ui.control.UIAxes
        axes8             matlab.ui.control.UIAxes
        axes1             matlab.ui.control.UIAxes
        uipanel2          matlab.ui.container.Panel
        waitbarAxesSync   matlab.ui.control.UIAxes
    end

    % SINCRONIZACION MATLAB code for sincronizacion.fig
%      SINCRONIZACION, by itself, creates a new SINCRONIZACION or raises the existing
%      singleton*.
%
%      H = SINCRONIZACION returns the handle to a new SINCRONIZACION or the handle to
%      the existing singleton*.
%
%      SINCRONIZACION('CALLBACK',hObject,eventData,handles,...) calls the local
%      function named CALLBACK in SINCRONIZACION.M with the given input arguments.
%
%      SINCRONIZACION('Property','Value',...) creates a new SINCRONIZACION or raises the
%      existing singleton*.  Starting from the left, property value pairs are
%      applied to the GUI before sincronizacion_OpeningFcn gets called.  An
%      unrecognized property name or invalid value makes property application
%      stop.  All inputs are passed to sincronizacion_OpeningFcn via varargin.


% Edit the above text to modify the response to help sincronizacion

% Last Modified: v1.8 23-Sep-2024 ‏‎14:00:00
    methods (Access = private)
        function initWaitbarProSync(app, handles)
            %%
            waitbarAxesSync = handles.waitbarAxesSync;
            
            % wait bar caracter�sticas!
            esfera = scatter(waitbarAxesSync, 0, 0, 'LineWidth', 1);
            esfera.MarkerFaceColor = [0 0.25 0.25];
            esfera.MarkerEdgeColor = [0 0.5 0.5];
            waitbarAxesSync.YLim = [0 1];
            waitbarAxesSync.XLim = [0 1];
            waitbarAxesSync.XLim = [0 1];
            waitbarAxesSync.XMinorGrid = 'on';
            waitbarAxesSync.XTickLabel = {};
            waitbarAxesSync.XMinorTick = 'on';
            waitbarAxesSync.YColor = [1 1 1];
            hold(waitbarAxesSync, 'on')
            % area
            x = [0; 0.001];
            Y = [0.5;0.5];
            area(waitbarAxesSync, x,Y,...
                'FaceColor', [102, 205, 170] / 255, 'EdgeColor', [32, 178, 170] / 255);
        end
        
        function moverWaitbarSync(app, handles, porcentaje, transicion)
            %% porcentaje: 0 a 1
            % transicion: valor entre 0 y 1
            barra = handles.waitbarAxesSync.Children(1);
            esfera = handles.waitbarAxesSync.Children(2);
            barra.XData = [0 porcentaje];
            esfera.XData = porcentaje;
            
            %-% normal
            if porcentaje < transicion
                barra.YData = [0 porcentaje];
                esfera.YData = 0.5;
                esfera.YData = porcentaje;
                barra.FaceColor = [102, 205, 170] / 255; % azul!
                barra.EdgeColor = [32, 178, 170] / 255;
            
            else
                barra.YData = [1 1];
                barra.FaceColor = [255, 215, 0] / 255; % amarillo
                barra.EdgeColor = [250, 250, 210] / 255;
                esfera.YData = transicion;
            end
            
            %-% conteo
            tamText = 20;
            colorLetter = [0 0 1];
            if porcentaje < transicion / 3
                handles.msjText.String = '3';
            
            elseif (porcentaje >= transicion / 3) && (porcentaje < transicion * 2 / 3)
                handles.msjText.String = '2';
            
            elseif (porcentaje >= transicion * 2 / 3) && (porcentaje < transicion)
                handles.msjText.String = '1';
            
            else
                handles.msjText.String = 'GO!';
                colorLetter = [1 0 0];
                handles.msjText.FontWeight = 'normal';
                tamText = 18;
            end
            
            handles.msjText.ForegroundColor = colorLetter;
            handles.msjText.FontSize = tamText;
            drawnow;
        end
        
        function plotEMGSync(app, handles, userData, transicion)
            %%
            global gestureNameSync timeGestureSync repetitionNum ...
                deviceType gForceObject
            
            %---% current
            emgs = userData.gestures.(gestureNameSync).data{repetitionNum,1}.emg;
            
            yMin = -1;
            yMax = 1;
            
            if deviceType == DeviceName.gForce
                freq = gForceObject.emgFreq;
            else
                optionsRecording = recordingConfigs();
                freq = optionsRecording.myo.emgSamplingRate;
            end
            
            xlimit = timeGestureSync * freq;
            
            transSTR = round(transicion * freq);
            
            %-% textos
            muestrasAq = length(emgs);
            handles.numPointsText.String = num2str(muestrasAq);
            
            options = recordingConfigs();
            minSignalLength = options.recording.minSignalLength;
            if muestrasAq < minSignalLength/100 * timeGestureSync * freq
                handles.numPointsText.ForegroundColor = [1 0 0];
            else
                handles.numPointsText.ForegroundColor = [0 0 1];
            end
            
            %-% Plot on axes
            for cidx = 1:8
                idxSTR = num2str(cidx);
                ax = handles.(['axes' idxSTR]);
                %     cmd = ['plot(' ax ', emgs(:,' idxSTR '));'];
                %     cmd = [cmd 'xlim(' ax ', [0 xlimit]);'];
                %     cmd = [cmd 'ylim(' ax ', [yMin yMax]);'];
                %     cmd = [cmd 'line([transSTR transSTR],[yMin yMax],''LineWidth'',2'...
                %         ',''Color'',[1 0.8043 0],''Parent'',' ax ');'];
                %     eval(cmd);
            
                plot(ax, emgs(:,cidx));
                xlim(ax, [0 xlimit]);
                ylim(ax, [yMin yMax]);
                line([transSTR transSTR],[yMin yMax],'LineWidth',2 ...
                    ,'Color',[1 0.8043 0],'Parent', ax);
            end
        end
        
        function myTimer = timerCollectionSync(app)
            %%
            global timeGestureSync
            
            %-% Single shot timer
            myTimer = timer('Name', 'Sync Timer');
            myTimer.StartFcn = @timerStartCollection;
            myTimer.TimerFcn = @timerFuncCollection;
            myTimer.StopFcn = @timerStartCollection;
            myTimer.StartDelay = timeGestureSync;
        end
        
function myTimer = timerWaitbarSync(app, handles, transicion)
    global timeGestureSync;

    % Crear el temporizador
    myTimer = timer;
    myTimer.ExecutionMode = 'fixedRate';
    myTimer.Period = 0.1;

    % Calcular el número de ejecuciones basado en el tiempo
    numExecutions = floor(timeGestureSync / 0.1);


    myTimer.TasksToExecute = numExecutions;

    % Configurar la función de temporizador
    myTimer.TimerFcn = @(timer, evnt) moverWaitbarSync(app, handles, timer.TasksExecuted/numExecutions, transicion/timeGestureSync);
end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function sincronizacion_OpeningFcn(app, varargin)
            % --- Executes just before sincronizacion is made visible.
            
            % Ensure that the app appears on screen when run
            movegui(app.figure1, 'onscreen');
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to sincronizacion (see VARARGIN)
            
            % Choose default command line output for sincronizacion
            handles.output = hObject;
            
            % Update handles structure
            guidata(hObject, handles);
            
            movegui(hObject,'center'); % mostrando en el centro
            initWaitbarProSync(app, handles);
                        global gestureNameSync timeGestureSync numOfRepetitions repetitionNum;


% config
options = syncConfigs(); % configuration now in specified file
gestureNameSync = options.gestureNameSync;
timeGestureSync = options.timeGestureSync;
numOfRepetitions = options.numOfRepetitions;
repetitionNum = 1;
            
%... debug!
str = 'First, the sync gesture...';
%uiwait(msgbox(str, 'INFORMATION','help'));

handles.sampleNumberText.String = [num2str(repetitionNum) ' / ' num2str(numOfRepetitions)];

            drawnow;
        end

        % Close request function: figure1
        function figure1_CloseRequestFcn(app, event)
            % --- Executes when user attempts to close figure1.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            %%
            % hObject    handle to figure1 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: delete(hObject) closes the figure
            global numOfRepetitions repetitionNum ;
            global userData
            if repetitionNum <= numOfRepetitions
                drawnow
                delete(gcf);
                str = {'Matlab must be restarted'
                    'Please, complete the samples!'
                    'In the case of an error, sadly, Matlab must be restarted!'};
                msgbox(str,'WARNING','warn');
            
            else
                clearvars -global -except myoObject deviceType gForceObject ...
                    userData;
                userData.counterGesture = 1;
                drawnow
                delete(gcf);
            end
        end

        % Button pushed function: recordPushbutton
        function recordPushbutton_Callback(app, event)
            % --- Executes on button press in recordPushbutton.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to recordPushbutton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            global userData numOfRepetitions repetitionNum;

            % userData = loadUserData(userData); % ya no leemos pues todo se graba en
            % userData, y no se puede restaurar el proceso
            options = syncConfigs();
            
            handles.recordPushbutton.Enable = 'off';
            handles.replayPushbutton.Enable = 'off';
            
            %-% Iniciar los timers
            %2 + 3*rand;
            timeToStartRecording = options.deadTime + options.windowTime*rand;
            userData.lastTransitionTime = timeToStartRecording;
            timerWaitbar = timerWaitbarSync(app, handles, timeToStartRecording);
            start(timerWaitbar);
            
            %-% Adquirir datos del Myo
            timerEMGRecording = timerCollectionSync(app);
            start(timerEMGRecording);
            wait(timerEMGRecording);
            
            %-% Desactivar los timers
            stop(timerWaitbar);
            stop(timerEMGRecording);
            delete(timerWaitbar);
            delete(timerEMGRecording);
            drawnow;
            
            % reseting waitbar
            moverWaitbarSync(app, handles, 0, 0);
            
            %-% Handling errors
            % In the case an error with myo
            global errorInData myoErrorType
            if errorInData
                switch myoErrorType
                    case '65535'
                        uiwait(warndlg({'Myo device not synced!'
                            ''
                            'Please, synchronize the device performing a steady waveOut.'
                            'Record again.'}, 'Not synced!', 'modal'));
            
                    otherwise
                        % empty emg, or gForce error?
                        uiwait(errordlg({'No data received!'
                            ''
                            'Try recording again.'
                            ''
                            'If the error keeps appearing, try any of the following:'
                            '   1. Restart the device'
                            '   2. Check the battery'
                            '   3. Unplug and plug the dongle USB'
                            '   4. Restart matlab'}, 'ERROR!', 'modal'));
                end
            
                handles.recordPushbutton.Enable = 'off';
                handles.replayPushbutton.Enable = 'on';
                drawnow
                return;
            end
            
            %-% Graficar la se�al EMG
            plotEMGSync(app, handles, userData, timeToStartRecording);
            drawnow;
            
            %-% Grabar
            saveUserData(); % aw graba en el archivo principal userData
            drawnow;
            
            repetitionNum = repetitionNum + 1;
            if repetitionNum > numOfRepetitions
                % the end
                handles.recordPushbutton.Enable = 'off';
                handles.replayPushbutton.Enable = 'on';
            else
                handles.recordPushbutton.Enable = 'on';
                handles.replayPushbutton.Enable = 'on';
                handles.sampleNumberText.String = ...
                    [num2str(repetitionNum) ' / ' num2str(numOfRepetitions)];
            end
        end

        % Button pushed function: replayPushbutton
        function replayPushbutton_Callback(app, event)
            % --- Executes on button press in replayPushbutton.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            %% hObject    handle to replayPushbutton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            global numOfRepetitions repetitionNum;
            repetitionNum = repetitionNum - 1;
            if repetitionNum <= 0
                repetitionNum = 1;
            end
            handles.sampleNumberText.String = ...
                [num2str(repetitionNum) ' / ' num2str(numOfRepetitions)];
            handles.recordPushbutton.Enable = 'on';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'SYNC';
            app.figure1.CloseRequestFcn = createCallbackFcn(app, @figure1_CloseRequestFcn, true);
            app.figure1.Tag = 'figure1';
            app.uipanel2 = uipanel(app.figure1);
            app.uipanel2.Tag = 'uipanel2';
            app.waitbarAxesSync = uiaxes(app.uipanel2);
            app.waitbarAxesSync.XLim = [0 1];
            app.waitbarAxesSync.YLim = [0 1];
            app.waitbarAxesSync.Tag = 'waitbarAxesSync';
            app.uipanel1 = uipanel(app.figure1);
            app.uipanel1.Title = 'EMG signal';
            app.uipanel1.Tag = 'uipanel1';
            app.axes1 = uiaxes(app.uipanel1);
            app.axes1.YLim = [-1 1];
            app.axes1.ALim = [0 1];
            app.axes1.Tag = 'axes1';
            app.axes8 = uiaxes(app.uipanel1);
            app.axes8.YLim = [-1 1];
            app.axes8.Tag = 'axes8';
            app.axes7 = uiaxes(app.uipanel1);
            app.axes7.YLim = [-1 1];
            app.axes7.Tag = 'axes7';
            app.axes6 = uiaxes(app.uipanel1);
            app.axes6.YLim = [-1 1];
            app.axes6.Tag = 'axes6';
            app.axes2 = uiaxes(app.uipanel1);
            app.axes2.YLim = [-1 1];
            app.axes2.Tag = 'axes2';
            app.axes3 = uiaxes(app.uipanel1);
            app.axes3.YLim = [-1 1];
            app.axes3.Tag = 'axes3';
            app.axes4 = uiaxes(app.uipanel1);
            app.axes4.YLim = [-1 1];
            app.axes4.Tag = 'axes4';
            app.axes5 = uiaxes(app.uipanel1);
            app.axes5.YLim = [-1 1];
            app.axes5.Tag = 'axes5';
            app.text2 = uilabel(app.uipanel1);
            app.text2.Tag = 'text2';
            app.text2.Text = 'Number of samples:';
            app.numPointsText = uilabel(app.uipanel1);
            app.numPointsText.Tag = 'numPointsText';
            app.numPointsText.Text = '';
            app.text4 = uilabel(app.uipanel1);
            app.text4.Tag = 'text4';
            app.text4.Text = '1';
            app.text5 = uilabel(app.uipanel1);
            app.text5.Tag = 'text5';
            app.text5.Text = '2';
            app.text6 = uilabel(app.uipanel1);
            app.text6.Tag = 'text6';
            app.text6.Text = '3';
            app.text7 = uilabel(app.uipanel1);
            app.text7.Tag = 'text7';
            app.text7.Text = '4';
            app.text8 = uilabel(app.uipanel1);
            app.text8.Tag = 'text8';
            app.text8.Text = '5';
            app.text9 = uilabel(app.uipanel1);
            app.text9.Tag = 'text9';
            app.text9.Text = '6';
            app.text10 = uilabel(app.uipanel1);
            app.text10.Tag = 'text10';
            app.text10.Text = '7';
            app.text11 = uilabel(app.uipanel1);
            app.text11.Tag = 'text11';
            app.text11.Text = '8';
            app.text12 = uilabel(app.figure1);
            app.text12.Tag = 'text12';
            app.text12.Text = 'SYNC GESTURE';
            app.recordPushbutton = uibutton(app.figure1, 'push');
            app.recordPushbutton.ButtonPushedFcn = createCallbackFcn(app, @recordPushbutton_Callback, true);
            app.recordPushbutton.Tag = 'recordPushbutton';
            app.recordPushbutton.Text = 'RECORD';
            app.replayPushbutton = uibutton(app.figure1, 'push');
            app.replayPushbutton.ButtonPushedFcn = createCallbackFcn(app, @replayPushbutton_Callback, true);
            app.replayPushbutton.Tag = 'replayPushbutton';
            app.replayPushbutton.Text = 'REPEAT';
            app.msjText = uilabel(app.figure1);
            app.msjText.Tag = 'msjText';
            app.msjText.Text = '';
            app.text14 = uilabel(app.figure1);
            app.text14.Tag = 'text14';
            app.text14.Text = 'Sample #:';
            app.sampleNumberText = uilabel(app.figure1);
            app.sampleNumberText.Tag = 'sampleNumberText';
            app.sampleNumberText.Text = '';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = sincronizacion_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)sincronizacion_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end