classdef survey < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        AnswersPanel            matlab.ui.container.Panel
        Question1               matlab.ui.container.ButtonGroup
        No_1                    matlab.ui.control.RadioButton
        Si_1                    matlab.ui.control.RadioButton
        NA_1                    matlab.ui.control.RadioButton
        Question2               matlab.ui.container.ButtonGroup
        No_2                    matlab.ui.control.RadioButton
        Si_2                    matlab.ui.control.RadioButton
        NA_2                    matlab.ui.control.RadioButton
        Question3               matlab.ui.container.ButtonGroup
        No_3                    matlab.ui.control.RadioButton
        Si_3                    matlab.ui.control.RadioButton
        NA_3                    matlab.ui.control.RadioButton
        Question4               matlab.ui.container.ButtonGroup
        No_4                    matlab.ui.control.RadioButton
        Si_4                    matlab.ui.control.RadioButton
        NA_4                    matlab.ui.control.RadioButton
        Question5               matlab.ui.container.ButtonGroup
        No_5                    matlab.ui.control.RadioButton
        Si_5                    matlab.ui.control.RadioButton
        NA_5                    matlab.ui.control.RadioButton
        Question6               matlab.ui.container.ButtonGroup
        No_6                    matlab.ui.control.RadioButton
        Si_6                    matlab.ui.control.RadioButton
        NA_6                    matlab.ui.control.RadioButton
        Question7               matlab.ui.container.ButtonGroup
        No_7                    matlab.ui.control.RadioButton
        Si_7                    matlab.ui.control.RadioButton
        NA_7                    matlab.ui.control.RadioButton
        TieneunmarcapasosLabel  matlab.ui.control.Label
        TienealgunaenfermedadmuscularLabel  matlab.ui.control.Label
        UtilizaalgnmedicamentorelacionadoconlosmsculosLabel  matlab.ui.control.Label
        ConsideraquedescansysealimentconnormalidadLabel  matlab.ui.control.Label
        Hautilizadoalgnestmuloenlasltimas24hAlcoholetcLabel  matlab.ui.control.Label
        Hatomadoalgunamedicacinenlasltimas24horasLabel  matlab.ui.control.Label
        HatenidocambiossignificativosensuestadodesaludLabel  matlab.ui.control.Label
        Save                    matlab.ui.control.StateButton
    end

    properties (Access = public)
        Property % Description
        answers
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Selection changed function: Question1
        function Question1SelectionChanged(app, event)
            selectedButton = app.Question1.SelectedObject;
            app.answers.Question1 = selectedButton.Text;  

        end

        % Selection changed function: Question2
        function Question2SelectionChanged(app, event)
            selectedButton = app.Question2.SelectedObject;
            app.answers.Question2 = selectedButton.Text;
        end

        % Selection changed function: Question3
        function Question3SelectionChanged(app, event)
            selectedButton = app.Question3.SelectedObject;
            app.answers.Question3 = selectedButton.Text;
        end

        % Selection changed function: Question4
        function Question4SelectionChanged(app, event)
            selectedButton = app.Question4.SelectedObject;
            app.answers.Question4 = selectedButton.Text;
        end

        % Selection changed function: Question5
        function Question5SelectionChanged(app, event)
            selectedButton = app.Question5.SelectedObject;
            app.answers.Question5 = selectedButton.Text;
        end

        % Selection changed function: Question6
        function Question6SelectionChanged(app, event)
            selectedButton = app.Question6.SelectedObject;
            app.answers.Question6 = selectedButton.Text;
        end

        % Selection changed function: Question7
        function Question7SelectionChanged(app, event)
            selectedButton = app.Question7.SelectedObject;
            app.answers.Question7 = selectedButton.Text;       
            
        end

        % Value changed function: Save
        function SaveValueChanged(app, event)
            global userData;
            errorMessage = '';

            questionObjects = {
                'Question1', app.Question1.SelectedObject, app.NA_1;
                'Question2', app.Question2.SelectedObject, app.NA_2;
                'Question3', app.Question3.SelectedObject, app.NA_3;
                'Question4', app.Question4.SelectedObject, app.NA_4;
                'Question5', app.Question5.SelectedObject, app.NA_5;
                'Question6', app.Question6.SelectedObject, app.NA_6;
                'Question7', app.Question7.SelectedObject, app.NA_7
            };
            
            for i = 1:size(questionObjects, 1)
                if (questionObjects{i, 2} == questionObjects{i, 3})
                    errorMessage = sprintf('%s%s has not been answered...!!!\n', errorMessage, questionObjects{i, 1});
                end
            end

            if ~isempty(errorMessage)
                uialert(app.UIFigure, errorMessage, 'Input error');
                return;
            end

            userData.userInfo.answers = app.answers;

            app.delete
            clear app
        end

        % Size changed function: UIFigure
        function UIFigureSizeChanged(app, event)
            screenSize = get(groot, 'ScreenSize');
            position = app.UIFigure.Position;
            position(1) = (screenSize(3) - position(3)) / 2;
            position(2) = (screenSize(4) - position(4)) / 2;
        
            app.UIFigure.Position = position;              
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest3(app, event)
            selection = uiconfirm(app.UIFigure, 'Are you sure you want to close this application?', 'Confirm closure', ...
            'Options', {'Yes', 'No'}, ...
            'DefaultOption', 1, 'CancelOption', 2);

            if strcmp(selection, 'Yes')
                delete(app.UIFigure);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest3, true);
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @UIFigureSizeChanged, true);
            app.Save = uibutton(app.UIFigure, 'state');
            app.Save.ValueChangedFcn = createCallbackFcn(app, @SaveValueChanged, true);
            app.Save.Text = 'Save';
            app.AnswersPanel = uipanel(app.UIFigure);
            app.AnswersPanel.Title = 'Answers';
            app.HatenidocambiossignificativosensuestadodesaludLabel = uilabel(app.AnswersPanel);
            app.HatenidocambiossignificativosensuestadodesaludLabel.Text = '1. ¿Ha tenido cambios significativos en su estado de salud?';
            app.Hatomadoalgunamedicacinenlasltimas24horasLabel = uilabel(app.AnswersPanel);
            app.Hatomadoalgunamedicacinenlasltimas24horasLabel.Text = '2. ¿Ha tomado alguna medicación en las últimas 24 horas?';
            app.Hautilizadoalgnestmuloenlasltimas24hAlcoholetcLabel = uilabel(app.AnswersPanel);
            app.Hautilizadoalgnestmuloenlasltimas24hAlcoholetcLabel.Text = '3. ¿Ha utilizado algún estímulo en las últimas 24 h? (Alcohol, etc.)';
            app.ConsideraquedescansysealimentconnormalidadLabel = uilabel(app.AnswersPanel);
            app.ConsideraquedescansysealimentconnormalidadLabel.Text = '4. ¿Considera que descansó y se alimentó con normalidad?';
            app.UtilizaalgnmedicamentorelacionadoconlosmsculosLabel = uilabel(app.AnswersPanel);
            app.UtilizaalgnmedicamentorelacionadoconlosmsculosLabel.Text = '5. ¿Utiliza algún medicamento relacionado con los músculos?';
            app.TienealgunaenfermedadmuscularLabel = uilabel(app.AnswersPanel);
            app.TienealgunaenfermedadmuscularLabel.Text = '6. ¿Tiene alguna enfermedad muscular?';
            app.TieneunmarcapasosLabel = uilabel(app.AnswersPanel);
            app.TieneunmarcapasosLabel.Text = '7. ¿Tiene un marcapasos?';
            app.Question7 = uibuttongroup(app.AnswersPanel);
            app.Question7.SelectionChangedFcn = createCallbackFcn(app, @Question7SelectionChanged, true);
            app.Question7.Title = 'Pregunta 7';
            app.NA_7 = uiradiobutton(app.Question7);
            app.NA_7.Visible = 'off';
            app.NA_7.Text = '';
            app.NA_7.Value = true;
            app.Si_7 = uiradiobutton(app.Question7);
            app.Si_7.Text = 'Sí';
            app.No_7 = uiradiobutton(app.Question7);
            app.No_7.Text = 'No';
            app.Question6 = uibuttongroup(app.AnswersPanel);
            app.Question6.SelectionChangedFcn = createCallbackFcn(app, @Question6SelectionChanged, true);
            app.Question6.Title = 'Pregunta 6';
            app.NA_6 = uiradiobutton(app.Question6);
            app.NA_6.Visible = 'off';
            app.NA_6.Text = '';
            app.NA_6.Value = true;
            app.Si_6 = uiradiobutton(app.Question6);
            app.Si_6.Text = 'Sí';
            app.No_6 = uiradiobutton(app.Question6);
            app.No_6.Text = 'No';
            app.Question5 = uibuttongroup(app.AnswersPanel);
            app.Question5.SelectionChangedFcn = createCallbackFcn(app, @Question5SelectionChanged, true);
            app.Question5.Title = 'Pregunta 5';
            app.NA_5 = uiradiobutton(app.Question5);
            app.NA_5.Visible = 'off';
            app.NA_5.Text = '';
            app.NA_5.Value = true;
            app.Si_5 = uiradiobutton(app.Question5);
            app.Si_5.Text = 'Sí';
            app.No_5 = uiradiobutton(app.Question5);
            app.No_5.Text = 'No';
            app.Question4 = uibuttongroup(app.AnswersPanel);
            app.Question4.SelectionChangedFcn = createCallbackFcn(app, @Question4SelectionChanged, true);
            app.Question4.Title = 'Pregunta 4';
            app.NA_4 = uiradiobutton(app.Question4);
            app.NA_4.Visible = 'off';
            app.NA_4.Text = '';
            app.NA_4.Value = true;
            app.Si_4 = uiradiobutton(app.Question4);
            app.Si_4.Text = 'Sí';
            app.No_4 = uiradiobutton(app.Question4);
            app.No_4.Text = 'No';
            app.Question3 = uibuttongroup(app.AnswersPanel);
            app.Question3.SelectionChangedFcn = createCallbackFcn(app, @Question3SelectionChanged, true);
            app.Question3.Title = 'Pregunta 3';
            app.NA_3 = uiradiobutton(app.Question3);
            app.NA_3.Visible = 'off';
            app.NA_3.Text = '';
            app.NA_3.Value = true;
            app.Si_3 = uiradiobutton(app.Question3);
            app.Si_3.Text = 'Sí';
            app.No_3 = uiradiobutton(app.Question3);
            app.No_3.Text = 'No';
            app.Question2 = uibuttongroup(app.AnswersPanel);
            app.Question2.SelectionChangedFcn = createCallbackFcn(app, @Question2SelectionChanged, true);
            app.Question2.Title = 'Pregunta 2';
            app.NA_2 = uiradiobutton(app.Question2);
            app.NA_2.Visible = 'off';
            app.NA_2.Text = '';
            app.NA_2.Value = true;
            app.Si_2 = uiradiobutton(app.Question2);
            app.Si_2.Text = 'Sí';
            app.No_2 = uiradiobutton(app.Question2);
            app.No_2.Text = 'No';
            app.Question1 = uibuttongroup(app.AnswersPanel);
            app.Question1.SelectionChangedFcn = createCallbackFcn(app, @Question1SelectionChanged, true);
            app.Question1.Title = 'Pregunta 1';
            app.NA_1 = uiradiobutton(app.Question1);
            app.NA_1.Visible = 'off';
            app.NA_1.Text = 'NA';
            app.NA_1.Value = true;
            app.Si_1 = uiradiobutton(app.Question1);
            app.Si_1.Text = 'Sí';
            app.No_1 = uiradiobutton(app.Question1);
            app.No_1.Text = 'No';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = survey

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end